classdef garmin_frontend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubmitButton                    matlab.ui.control.Button
        phaseShift1EditField            matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        cosLabel                        matlab.ui.control.Label
        PhaseShift2EditField            matlab.ui.control.NumericEditField
        Frequency2EditField             matlab.ui.control.NumericEditField
        Amplitude2EditField             matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        sinLabel                        matlab.ui.control.Label
        Frequency1EditField             matlab.ui.control.NumericEditField
        SendMessageEditField            matlab.ui.control.EditField
        SendMessageEditFieldLabel       matlab.ui.control.Label
        Amplitude1EditField             matlab.ui.control.NumericEditField
        VolumedBKnob                    matlab.ui.control.Knob
        VolumedBKnobLabel               matlab.ui.control.Label
        Meter                           audio.ui.control.Meter
        UpdateButton                    slrealtime.ui.control.UpdateButton
        ParameterTable                  slrealtime.ui.control.ParameterTable
        CurrentDiverFeedbackGauge       matlab.ui.control.SemicircularGauge
        CurrentDiverFeedbackGaugeLabel  matlab.ui.control.Label
        setDataMatrixEditField          matlab.ui.control.EditField
        setDataMatrixEditFieldLabel     matlab.ui.control.Label
        loadwavfileButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Arduino % Description
        fileName % Description
        message % Description
        frequency1 % Description
        amplitude1 % Description
        phaseShift1 % Description
        frequency2 % Description
        amplitude2 % Description
        sineWave % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ParameterTable, setDataMatrixEditField
        function setDataMatrixEditFieldValueChanged(app, event)
            app.fileName = app.setDataMatrixEditField.Value;
            app.fileName = app.fileName + ".mat"
            save(app.fileName)
        end

        % Button pushed function: loadwavfileButton
        function loadwavfileButtonPushed(app, event)
            load(app.fileName)
            [file,path] = uigetfile('*.wav')
            [audioData,fs] = audioread(file);
            soundsc(audioData,fs)
            TotalTime = length(audioData)./fs
            %delay(TotalTime)
            pause(TotalTime)
            [audioData1,fs1] = audioread("test1.wav");
            soundsc(audioData1,fs1)

            a =  arduino;
            dataValue = readVoltage(a, 'A0')
            if (dataValue <= 1.25)
                rating = "worst";
            end 
            if (dataValue >= 1.25 && dataValue <= 2.5)
                rating = "bad";
            end 
            if (dataValue >= 2.5 && dataValue <= 3.75)
                rating = "better";
            end 
            if (dataValue >= 3.75)
                rating = "best";
            end 
            dataSet = [file, rating]
            a = cat(1,a, dataSet)
            save(app.fileName,'-append','a')

            synthesizer = speechClient("IBM");
            [speech, fs] = text2speech(synthesizer, app.message);
            soundsc(speech, fs);
        end

        % Value changed function: SendMessageEditField
        function SendMessageEditFieldValueChanged(app, event)
            app.message = app.Frequency1EditField.Value;
            app.message = app.fileName + 'mat'
            save(app.message)
        end

        % Value changed function: Frequency1EditField
        function Frequency1EditFieldValueChanged(app, event)
            app.frequency = app.Frequency1EditField.Value;
            app.frequency = app.fileName + 'mat'
            save(app.frequency)
        end

        % Value changed function: Amplitude1EditField
        function Amplitude1EditFieldValueChanged(app, event)
            app.amplitude1 = app.Frequency1EditField.Value;
            app.amplitude1 = app.fileName + 'mat'
            save(app.amplitude1)
        end

        % Value changed function: phaseShift1EditField
        function phaseShift1EditFieldValueChanged(app, event)
            app.phaseShift1 = app.Frequency1EditField.Value;
            app.phaseShift1 = app.fileName + 'mat'
            save(app.phaseShift1)
        end

        % Value changed function: Amplitude2EditField
        function Amplitude2EditFieldValueChanged(app, event)
            app.amplitude2 = app.Frequency1EditField.Value;
            app.amplitude2 = app.fileName + 'mat'
            save(app.amplitude2)
        end

        % Value changed function: Frequency2EditField
        function Frequency2EditFieldValueChanged(app, event)
            app.frequency2 = app.Frequency1EditField.Value;
            app.frequency2 = app.fileName + 'mat'
            save(app.frequency2)
        end

        % Value changed function: PhaseShift2EditField
        function PhaseShift2EditFieldValueChanged(app, event)
            app.phaseShift2 = app.Frequency1EditField.Value;
            app.phaseShift2 = app.fileName + 'mat'
            save(app.phaseShift2)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if (app.frequency1 && app.amplitude1 && app.phaseShift1 && app.frequency2 && app.amplitude2 && app.phaseShift2)
                a = arduino('E8:6B:EA:DE:A6:AE')
                writeDigitalPin(a,'D5',1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadwavfileButton = uibutton(app.UIFigure, 'push');
            app.loadwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadwavfileButtonPushed, true);
            app.loadwavfileButton.Text = 'load .wav file ';
            app.setDataMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.setDataMatrixEditFieldLabel.Text = 'setDataMatrix';
            app.setDataMatrixEditField = uieditfield(app.UIFigure, 'text');
            app.setDataMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @setDataMatrixEditFieldValueChanged, true);
            app.CurrentDiverFeedbackGaugeLabel = uilabel(app.UIFigure);
            app.CurrentDiverFeedbackGaugeLabel.Text = 'Current Diver Feedback';
            app.CurrentDiverFeedbackGauge = uigauge(app.UIFigure, 'semicircular');
            app.CurrentDiverFeedbackGauge.Limits = [0 5];
            app.ParameterTable = slrealtime.ui.control.ParameterTable(app.UIFigure);
            app.ParameterTable.SizeChangedFcn = createCallbackFcn(app, @setDataMatrixEditFieldValueChanged, true);
            app.UpdateButton = slrealtime.ui.control.UpdateButton(app.UIFigure);
            app.UpdateButton.UpdateText = 'Upload Audio File';
            app.Meter = uiaudiometer(app.UIFigure);
            app.Meter.Limits = [0 80];
            app.VolumedBKnobLabel = uilabel(app.UIFigure);
            app.VolumedBKnobLabel.Text = 'Volume (dB)';
            app.VolumedBKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumedBKnob.Limits = [0 70];
            app.Amplitude1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude1EditField.ValueChangedFcn = createCallbackFcn(app, @Amplitude1EditFieldValueChanged, true);
            app.SendMessageEditFieldLabel = uilabel(app.UIFigure);
            app.SendMessageEditFieldLabel.Text = 'Send Message';
            app.SendMessageEditField = uieditfield(app.UIFigure, 'text');
            app.SendMessageEditField.ValueChangedFcn = createCallbackFcn(app, @SendMessageEditFieldValueChanged, true);
            app.Frequency1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequency1EditField.ValueChangedFcn = createCallbackFcn(app, @Frequency1EditFieldValueChanged, true);
            app.sinLabel = uilabel(app.UIFigure);
            app.sinLabel.Text = 'sin(';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '+';
            app.Amplitude2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude2EditField.ValueChangedFcn = createCallbackFcn(app, @Amplitude2EditFieldValueChanged, true);
            app.Frequency2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequency2EditField.ValueChangedFcn = createCallbackFcn(app, @Frequency2EditFieldValueChanged, true);
            app.PhaseShift2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShift2EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseShift2EditFieldValueChanged, true);
            app.cosLabel = uilabel(app.UIFigure);
            app.cosLabel.Text = 'cos( ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = ')';
            app.phaseShift1EditField = uieditfield(app.UIFigure, 'numeric');
            app.phaseShift1EditField.ValueChangedFcn = createCallbackFcn(app, @phaseShift1EditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = garmin_frontend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end